classdef browseCells < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CellBrowserUIFigure  matlab.ui.Figure
        table_cells          matlab.ui.control.Table
        axis_pref3DScore     matlab.ui.control.UIAxes
        button_top100        matlab.ui.control.Button
        button_top10         matlab.ui.control.Button
        button_controls      matlab.ui.control.Button
        button_stickSta      matlab.ui.control.Button
        button_surfSta       matlab.ui.control.Button
        button_predictions   matlab.ui.control.Button
        axis_predScoresSR    matlab.ui.control.UIAxes
        label_cellnote       matlab.ui.control.Label
        axis_predScoresST    matlab.ui.control.UIAxes
        axis_predScoresRT    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        data % Description
        selectedCell % Description
        cdfProps % Description
        predPlotProps % Description
        selectedCells % Description
        notes % Description
        postHocIds % Description
    end

    methods (Access = private)
    
        function addRows(app)
            loadedFile = load('data/ids.mat');
            population = loadedFile.population;
            loadedFile = load('data/corrScores.mat');
            corrScores = loadedFile.corrScores;
            loadedFile = load('data/selectedCells.mat');
            
            app.selectedCells = loadedFile.selectedCells;
            app.notes = loadedFile.notes;
            
            goodCells = [population.nGen] - [population.nPostHoc] > 2;
            population = population(goodCells);
            corrScores = corrScores(goodCells);
            
            for ii=1:length(population)
                prefix = population(ii).prefix;
                runNum = population(ii).runNum;
                nGen = population(ii).nGen;
                nPosthoc = population(ii).nPostHoc;
                score_3d = population(ii).score_3d;
                corr_s = corrScores(ii).s;
                corr_r = corrScores(ii).r;
                corr_t = corrScores(ii).t;
                corr_surf = corrScores(ii).surf;
                
                app.data(ii,:) = [app.selectedCells(ii) ~isempty(app.notes{ii}) prefix runNum nGen nPosthoc score_3d corr_s corr_r corr_t corr_surf];
                
                app.postHocIds{ii} = population(ii).postHocIds;
            end
            app.table_cells.Data = app.data;
        end

        function plot3dScores(app)
            [n,edges] = histcounts(app.data(:,7),100,'BinLimits',[-1 1],'Normalization','cdf');
            centers = (edges(1:end-1) + edges(2:end)) / 2;
            
            hold(app.axis_pref3DScore,'on');
            plot(app.axis_pref3DScore,centers(centers>=0),n(centers>=0),'linewidth',4);
            plot(app.axis_pref3DScore,centers(centers<0),n(centers<0),'linewidth',4);
            app.axis_pref3DScore.LineWidth = 2;
            
            app.cdfProps.n = n;
            app.cdfProps.centers = centers;
        end
        
        function plotPredScores(app)
            corrScores = app.data(:,8:11);
            % newboxplot(app,corrScores);
        end
        
        % function newboxplot(app,corrScores,idx)
        % hold(app.axis_predScoresSR,'on');
        % 
        % nGrps = size(corrScores,2);
        % nPts = size(corrScores,1);
        % groups = repmat(1:nGrps,nPts,1);
        % jitter = 0.2*rand(nPts,1) - 0.1;
        % medians = median(corrScores);
        % 
        % for ii=1:nGrps
        %     x = (ii+jitter);
        %     y = corrScores(:,ii);
        %     
        %     plot(app.axis_predScoresSR,x,y,'.','color',[0.5 0.5 0.5],'MarkerSize',15);
        %     
        %     if exist('idx','var')
        %         if isfield(app.predPlotProps,'h')
        %             delete(app.predPlotProps.h);
        %         end
        %         app.predPlotProps.h = plot(app.axis_predScoresSR,x(idx),y(idx),'.','color','r','MarkerSize',10);
        %     end
        % end
        % app.axis_predScoresSR.XLim = [0 5];
        % end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addRows(app);
            plot3dScores(app);
            plotPredScores(app);
        end

        % Cell selection callback: table_cells
        function table_cellsCellSelection(app, event)
            app.selectedCell.prefix = num2str(app.data(event.Indices(1),3));
            app.selectedCell.runNum = num2str(app.data(event.Indices(1),4));
            
            % h = app.axis_pref3DScore;
            [~,centerIdx] = min(abs(app.cdfProps.centers - app.data(event.Indices(1),7)));
            
            if isfield(app.cdfProps,'dotH') && ~isempty(app.cdfProps.dotH)
                delete(app.cdfProps.dotH);
            end
            
            app.cdfProps.dotH = plot(app.axis_pref3DScore,app.cdfProps.centers(centerIdx),app.cdfProps.n(centerIdx),'k.','MarkerSize',35);
            
            [sorted,idx] = sort(app.data(event.Indices(1),8:10),'descend');
            win = num2str(round(sorted(1)-sorted(2),2));
            switch(idx(1))
                case 1; app.label_cellnote.Text = ['shaft wins by ' win '; '];
                case 2; app.label_cellnote.Text = ['root wins by ' win '; '];
                case 3; app.label_cellnote.Text = ['term wins by ' win '; '];
            end
            
            if ~isempty(app.postHocIds{event.Indices(1)})
                app.label_cellnote.Text = [app.label_cellnote.Text 'posthocs: ' num2str(app.postHocIds{event.Indices(1)}) '; '];
            end
            if ~isempty(app.notes{event.Indices(1)})
                app.label_cellnote.Text = [app.label_cellnote.Text app.notes{event.Indices(1)}];
            end
            
        end

        % Button pushed function: button_top100
        function button_top100Pushed(app, event)
            tXbase = '/Users/ramanujan/Dropbox/Documents/Hopkins/NHP2PV4/projectMaskedGA3D/analysis/plots/population';
            t100File = [tXbase '/topHundred/' app.selectedCell.prefix '_r-' app.selectedCell.runNum '_topHundred.png'];
            figure('pos',[1,1,2369,1344]);
            imshow(t100File);
        end

        % Button pushed function: button_top10
        function button_top10Pushed(app, event)
            tXbase = '/Users/ramanujan/Dropbox/Documents/Hopkins/NHP2PV4/projectMaskedGA3D/analysis/plots/population';
            t10File = [tXbase '/topTen/' app.selectedCell.prefix '_r-' app.selectedCell.runNum '_topTen.png'];
            figure
            imshow(t10File);
        end

        % Button pushed function: button_controls
        function button_controlsPushed(app, event)
            tXbase = '/Users/ramanujan/Dropbox/Documents/Hopkins/NHP2PV4/projectMaskedGA3D/analysis/plots/population';
            controlsFile = [tXbase '/controls/' app.selectedCell.prefix '_r-' app.selectedCell.runNum '_controlComparisons.png'];
            figure
            imshow(controlsFile);
        end

        % Button pushed function: button_stickSta
        function button_stickStaPushed(app, event)
            stickStaFile = ['plots/icosta/icosta_' app.selectedCell.prefix '_r-' app.selectedCell.runNum '_all_linMult'];
            openfig(stickStaFile);
        end

        % Button pushed function: button_surfSta
        function button_surfStaPushed(app, event)
            surfStaFile = ['plots/icosta_surf/icosta_surf_' app.selectedCell.prefix '_r-' app.selectedCell.runNum '_all_linMult'];
            openfig(surfStaFile);
        end

        % Button pushed function: button_predictions
        function button_predictionsPushed(app, event)
            predFile = ['plots/prediction/icostaPred3_' app.selectedCell.prefix '_r-' app.selectedCell.runNum '_multSta.png'];
            figure
            imshow(predFile);
        end

        % Cell edit callback: table_cells
        function table_cellsCellEdit(app, event)
            if event.Indices(2) == 1
                app.selectedCells(event.Indices(1)) = str2double(event.EditData);
                selectedCells = app.selectedCells;
                save('data/selectedCells.mat','selectedCells','-append');
            elseif event.Indices(2) == 2
                prefix = num2str(app.data(event.Indices(1),3));
                runNum = num2str(app.data(event.Indices(1),4));
                fid = fopen('plots/notes.txt', 'a+');
                fprintf(fid, '%s: %s: %s\n', prefix, runNum,event.EditData);
                fclose(fid);
                
                app.notes{event.Indices(1)} = event.EditData;
                notes = app.notes;
                save('data/selectedCells.mat','notes','-append');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CellBrowserUIFigure = uifigure;
            app.CellBrowserUIFigure.Name = 'Cell Browser';
            app.table_cells = uitable(app.CellBrowserUIFigure);
            app.table_cells.ColumnName = {'select'; 'notes'; 'prefix'; 'runNum'; 'nGen'; 'nPosthoc'; 'score_3d'; 'corr_s'; 'corr_r'; 'corr_t'; 'corr_surf'};
            app.table_cells.RowName = {};
            app.table_cells.ColumnEditable = [true true false false false false false false false false];
            app.table_cells.CellEditCallback = createCallbackFcn(app, @table_cellsCellEdit, true);
            app.table_cells.CellSelectionCallback = createCallbackFcn(app, @table_cellsCellSelection, true);
            app.axis_pref3DScore = uiaxes(app.CellBrowserUIFigure);
            title(app.axis_pref3DScore, '3D Preferences')
            xlabel(app.axis_pref3DScore, '3D Preference Score')
            ylabel(app.axis_pref3DScore, 'Probability')
            app.axis_pref3DScore.XLim = [-1 1];
            app.button_top100 = uibutton(app.CellBrowserUIFigure, 'push');
            app.button_top100.ButtonPushedFcn = createCallbackFcn(app, @button_top100Pushed, true);
            app.button_top100.Text = 'Top 100';
            app.button_top10 = uibutton(app.CellBrowserUIFigure, 'push');
            app.button_top10.ButtonPushedFcn = createCallbackFcn(app, @button_top10Pushed, true);
            app.button_top10.Text = 'Top 10';
            app.button_controls = uibutton(app.CellBrowserUIFigure, 'push');
            app.button_controls.ButtonPushedFcn = createCallbackFcn(app, @button_controlsPushed, true);
            app.button_controls.Text = 'Controls';
            app.button_stickSta = uibutton(app.CellBrowserUIFigure, 'push');
            app.button_stickSta.ButtonPushedFcn = createCallbackFcn(app, @button_stickStaPushed, true);
            app.button_stickSta.Text = 'Stick STA';
            app.button_surfSta = uibutton(app.CellBrowserUIFigure, 'push');
            app.button_surfSta.ButtonPushedFcn = createCallbackFcn(app, @button_surfStaPushed, true);
            app.button_surfSta.Text = 'Surf STA';
            app.button_predictions = uibutton(app.CellBrowserUIFigure, 'push');
            app.button_predictions.ButtonPushedFcn = createCallbackFcn(app, @button_predictionsPushed, true);
            app.button_predictions.Text = 'Predictions';
            app.axis_predScoresSR = uiaxes(app.CellBrowserUIFigure);
            title(app.axis_predScoresSR, 'SR')
            xlabel(app.axis_predScoresSR, 'S')
            ylabel(app.axis_predScoresSR, 'R')
            app.label_cellnote = uilabel(app.CellBrowserUIFigure);
            app.label_cellnote.Text = '';
            app.axis_predScoresST = uiaxes(app.CellBrowserUIFigure);
            title(app.axis_predScoresST, 'RT')
            xlabel(app.axis_predScoresST, 'R')
            ylabel(app.axis_predScoresST, 'T')
            app.axis_predScoresRT = uiaxes(app.CellBrowserUIFigure);
            title(app.axis_predScoresRT, 'TS')
            xlabel(app.axis_predScoresRT, 'T')
            ylabel(app.axis_predScoresRT, 'S')
        end
    end

    methods (Access = public)

        % Construct app
        function app = browseCells

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CellBrowserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CellBrowserUIFigure)
        end
    end
end