classdef SimpleUnimodal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VisualTrialsPanel               matlab.ui.container.Panel
        brightnessEditFieldMax          matlab.ui.control.NumericEditField
        durationvisualEditFieldMax      matlab.ui.control.NumericEditField
        sizeEditFieldMax                matlab.ui.control.NumericEditField
        sizeLabel                       matlab.ui.control.Label
        sizeEditFieldMin                matlab.ui.control.NumericEditField
        brightnessLabel                 matlab.ui.control.Label
        brightnessEditFieldMin          matlab.ui.control.NumericEditField
        durationLabel                   matlab.ui.control.Label
        durationvisualEditFieldMin      matlab.ui.control.NumericEditField
        dataEditFieldLabel              matlab.ui.control.Label
        datavisualEditField             matlab.ui.control.EditField
        radiusEditFieldMax              matlab.ui.control.NumericEditField
        radiusLabel                     matlab.ui.control.Label
        radiusEditFieldMin              matlab.ui.control.NumericEditField
        VisualTrialParametersLabel      matlab.ui.control.Label
        numTrialsEditFieldLabel         matlab.ui.control.Label
        numVisualTrialsEditField        matlab.ui.control.NumericEditField
        AddVisualTrialsButton           matlab.ui.control.Button
        eyewindowradiusLabel            matlab.ui.control.Label
        eyewindowradiusVisualEditField  matlab.ui.control.NumericEditField
        EStimTrialsPanel                matlab.ui.container.Panel
        ChannelSelectionPanel           matlab.ui.container.Panel
        PortDropDownLabel               matlab.ui.control.Label
        PortDropDown                    matlab.ui.control.DropDown
        ChannelSpinnerLabel             matlab.ui.control.Label
        ChannelSpinner                  matlab.ui.control.Spinner
        AddChanButton                   matlab.ui.control.Button
        ChanListListBoxLabel            matlab.ui.control.Label
        ChanListListBox                 matlab.ui.control.ListBox
        RemoveChanButton                matlab.ui.control.Button
        ClearChans                      matlab.ui.control.Button
        PostTriggerDelayEditFieldLabel  matlab.ui.control.Label
        PostTriggerDelayEditField       matlab.ui.control.NumericEditField
        TriggerSourceDropDownLabel      matlab.ui.control.Label
        TriggerSourceDropDown           matlab.ui.control.DropDown
        PulseRepetitionDropDownLabel    matlab.ui.control.Label
        PulseRepetitionDropDown         matlab.ui.control.DropDown
        NumberPulsesEditFieldLabel      matlab.ui.control.Label
        NumberPulsesEditField           matlab.ui.control.NumericEditField
        PulseTrainPeriodEditFieldLabel  matlab.ui.control.Label
        PulseTrainPeriodEditField       matlab.ui.control.NumericEditField
        PostStimRefractoryEditFieldLabel  matlab.ui.control.Label
        PostStimRefractoryEditField     matlab.ui.control.NumericEditField
        StimShapeDropDownLabel          matlab.ui.control.Label
        StimShapeDropDown               matlab.ui.control.DropDown
        StimPolarityDropDownLabel       matlab.ui.control.Label
        StimPolarityDropDown            matlab.ui.control.DropDown
        d1EditFieldLabel                matlab.ui.control.Label
        d1EditField                     matlab.ui.control.NumericEditField
        d2EditFieldLabel                matlab.ui.control.Label
        d2EditField                     matlab.ui.control.NumericEditField
        dpEditFieldLabel                matlab.ui.control.Label
        dpEditField                     matlab.ui.control.NumericEditField
        a1EditFieldLabel                matlab.ui.control.Label
        a1EditField                     matlab.ui.control.NumericEditField
        a2EditFieldLabel                matlab.ui.control.Label
        a2EditField                     matlab.ui.control.NumericEditField
        EnableAmpSettleCheckBox         matlab.ui.control.CheckBox
        PreStimAmpSettleEditFieldLabel  matlab.ui.control.Label
        PreStimAmpSettleEditField       matlab.ui.control.NumericEditField
        PostStimAmpSettleEditFieldLabel  matlab.ui.control.Label
        PostStimAmpSettleEditField      matlab.ui.control.NumericEditField
        MaintainAmpSettleDuringPulseTrainCheckBox  matlab.ui.control.CheckBox
        EnableChargeRecoveryCheckBox    matlab.ui.control.CheckBox
        PostStimChargeRecoveryOnEditFieldLabel  matlab.ui.control.Label
        PostStimChargeRecoveryOnEditField  matlab.ui.control.NumericEditField
        PostStimChargeRecoveryOffEditFieldLabel  matlab.ui.control.Label
        PostStimChargeRecoveryOffEditField  matlab.ui.control.NumericEditField
        numTrialsEditFieldLabel_2       matlab.ui.control.Label
        numEStimTrialsEditField         matlab.ui.control.NumericEditField
        AddEStimTrialsButton            matlab.ui.control.Button
        EyeWindowCoordsYEStimEditField  matlab.ui.control.NumericEditField
        EyeWindowCoordsXYLabel          matlab.ui.control.Label
        EyeWindowCoordsXEStimEditField  matlab.ui.control.NumericEditField
        EyeWindowRadiusEditFieldLabel   matlab.ui.control.Label
        EyeWindowRadiusEStimEditField   matlab.ui.control.NumericEditField
        DurationLabel                   matlab.ui.control.Label
        DurationEStimEditField          matlab.ui.control.NumericEditField
        DataLabel                       matlab.ui.control.Label
        DataEStimEditField              matlab.ui.control.EditField
        GenerationPanel                 matlab.ui.container.Panel
        toXMLButton                     matlab.ui.control.Button
        XMLtoDBButton                   matlab.ui.control.Button
        TrialListListBoxLabel           matlab.ui.control.Label
        TrialListListBox                matlab.ui.control.ListBox
        filepathEditFieldLabel          matlab.ui.control.Label
        filepathEditField               matlab.ui.control.EditField
        ShuffleButton                   matlab.ui.control.Button
        ClearTrialsButton               matlab.ui.control.Button
        GenerateButton                  matlab.ui.control.Button
        ClearTrialListAfterGeneratingCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        trialList % list of all of the trials
        chanList% list of all the chans 
    end
    
    methods (Access = public)
        
        function [] = addVisualTrials(app)
            numTrials = app.numVisualTrialsEditField.Value;
            for i = 1:numTrials
                gaussSpec = makeGaussSpec(app);
                targetEyeWinCoords = makeTargetEyeWinCoords(app, gaussSpec.getXCenter, gaussSpec.getYCenter);
                targetEyeWinSize = app.eyewindowradiusVisualEditField.Value; 
                duration = randomBetween(app.durationvisualEditFieldMin.Value, app.durationvisualEditFieldMax.Value);
                data = app.datavisualEditField.Value;
                visualTrial = org.xper.allen.blockgen.VisualTrial(gaussSpec, targetEyeWinCoords, double(targetEyeWinSize), double(duration), string(data));
                app.trialList = [app.trialList visualTrial];
            end
        end
        
        function [] = addEStimtrials(app)
            numTrials = app.numEStimTrialsEditField.Value;
            for i = 1:numTrials
                eStimObjDataEntry = makeEStimObjDataEntry(app);
                targetEyeWinCoords = makeTargetEyeWinCoords(app, app.EyeWindowCoordsXEStimEditField.Value, app.EyeWindowCoordsYEStimEditField.Value);
                targetEyeWinSize = app.EyeWindowRadiusEStimEditField.Value;
                duration = app.DurationEStimEditField.Value;
                data = app.DataEStimEditField.Value;
                eStimTrial= org.xper.allen.blockgen.EStimTrial(eStimObjDataEntry, targetEyeWinCoords,targetEyeWinSize, duration, data);
                app.trialList = [app.trialList eStimTrial];
            end
        end
        
        function XML = toXML(app)
            s= com.thoughtworks.xstream.XStream();
            s.setMode(com.thoughtworks.xstream.XStream.NO_REFERENCES);
            %convert to ArrayList
            trialArrayList = java.util.ArrayList();
            for i = 1:length(app.trialList)
                trialArrayList.add(app.trialList(i));
            end
            
            XML = s.toXML(trialArrayList);
        end
    end


    methods (Access = private)
        
        function gaussSpec = makeGaussSpec(app)
            [xCenter, yCenter] = randomWithinRadius(app.radiusEditFieldMin.Value, app.radiusEditFieldMax.Value);
            size = randomBetween(app.sizeEditFieldMin.Value, app.sizeEditFieldMax.Value);
            brightness = randomBetween(app.brightnessEditFieldMin.Value, app.brightnessEditFieldMax.Value);
            gaussSpec = org.xper.allen.specs.GaussSpec(xCenter, yCenter, size, brightness);
        end
        
        function targetEyeWinCoords = makeTargetEyeWinCoords(~, x, y)
            targetEyeWinCoords = org.xper.drawing.Coordinates2D(x, y);
        end
        
        function eStimObjDataEntry = makeEStimObjDataEntry(app)
            if ~isempty(app.chanList)
                chans = strjoin(app.chanList, ',');
            else
                chans = [];
            end
            post_trigger_delay = app.PostTriggerDelayEditField.Value;
            trig_src = app.PulseRepetitionDropDown.Value;
            pulse_repetition = app.PulseRepetitionDropDown.Value;
            if pulse_repetition == "SinglePulse"
                num_pulses = 1;
                pulse_train_period = 0;
                post_stim_refractory_period = 0;
            elseif pulse_repetition == "PulseTrain"
                num_pulses =app.NumberPulsesEditField.Value;
                pulse_train_period = app.PulseTrainPeriodEditField.Value;
                post_stim_refractory_period = app.PostStimRefractoryEditField.Value;
            end
            stim_shape = app.StimShapeDropDown.Value;
            stim_polarity = app.StimPolarityDropDown.Value;
            d1 = app.d1EditField.Value;
            d2 = app.d2EditField.Value;
            dp = app.dpEditField.Value;
            a1 = app.a1EditField.Value;
            a2 = app.a2EditField.Value;
            enable_amp_settle = app.EnableAmpSettleCheckBox.Value;
            if enable_amp_settle==false
                pre_stim_amp_settle = 0;
                post_stim_amp_settle = 0;
                maintain_amp_settle_during_pulse_train = 0;
            elseif enable_amp_settle==true
                pre_stim_amp_settle = app.PreStimAmpSettleEditField.Value;
                post_stim_amp_settle = app.PostStimAmpSettleEditField.Value;
                if pulse_repetition == "PulseTrain"
                    maintain_amp_settle_during_pulse_train = app.MaintainAmpSettleDuringPulseTrainCheckBox.Value;
                elseif pulse_repetition == "SinglePulse"
                    maintain_amp_settle_during_pulse_train = false;
                end
            end
            enable_charge_recovery = app.EnableChargeRecoveryCheckBox.Value;
            if enable_charge_recovery == false
                post_stim_charge_recovery_on = 0;
                post_stim_charge_recovery_off = 0;
            elseif enable_charge_recovery == true
                post_stim_charge_recovery_on = app.PostStimChargeRecoveryOnEditField.Value;
                post_stim_charge_recovery_off= app.PostStimChargeRecoveryOffEditField.Value;
            end
            
            eStimObjDataEntry = org.xper.allen.db.vo.EStimObjDataEntry(chans, post_trigger_delay, trig_src, pulse_repetition, num_pulses, pulse_train_period, post_stim_refractory_period, stim_shape, stim_polarity, d1, d2, dp, a1, a2, enable_amp_settle, pre_stim_amp_settle, post_stim_amp_settle, maintain_amp_settle_during_pulse_train, enable_charge_recovery, post_stim_charge_recovery_on, post_stim_charge_recovery_off);
       
            
        end 
        
        
        function updateListBox(app)
            if ~isempty(app.trialList)
                app.TrialListListBox.Items = convertStringsToChars(string(app.trialList))';
            else
                app.TrialListListBox.Items={};
            end
        end
        
        function updateChanListBox(app)
            if ~isempty(app.chanList)
                app.ChanListListBox.Items = app.chanList;
            else
                app.ChanListListBox.Items = {};
            end
        end
        
        function shuffleTrials(app)
            app.trialList = app.trialList(randperm(length(app.trialList)));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            EnableAmpSettleCheckBoxValueChanged(app);
            EnableChargeRecoveryCheckBoxValueChanged(app);
            PulseRepetitionDropDownValueChanged(app);
            StimShapeDropDownValueChanged(app);
            
%             screenSize = get(groot,'ScreenSize');
%             screenWidth = screenSize(3);
%             screenHeight = screenSize(4);
%             left = screenWidth*0.1;
%             bottom = screenHeight*0.1;
%             width = screenWidth*0.8;
%             height = screenHeight*0.8;
%             drawnow;
%             app.UIFigure.Position = [left bottom width height];
        end

        % Button pushed function: AddVisualTrialsButton
        function AddVisualTrialsButtonPushed(app, event)
            addVisualTrials(app);
            
            updateListBox(app);
        end

        % Button pushed function: toXMLButton
        function toXMLButtonPushed(app, event)
        xml = toXML(app);    
        disp(xml);
        org.xper.allen.app.simpleestim.EStimTestXMLGen.toXmlFile(xml, app.filepathEditField.Value);
        end

        % Button pushed function: XMLtoDBButton
        function XMLtoDBButtonPushed(app, event)
            org.xper.allen.app.simpleestim.SimpleEStimGenerator.main(app.filepathEditField.Value);
        end

        % Button pushed function: AddChanButton
        function AddChanButtonPushed(app, event)
            chan = [app.PortDropDown.Value '-' num2str(app.ChannelSpinner.Value)];
            %check if chan is already in the list
            if ~any(strcmp(app.chanList, chan))
                app.chanList = convertCharsToStrings([app.chanList; chan]);
                updateChanListBox(app);
            end
            %disp(app.chanList)
        end

        % Button pushed function: RemoveChanButton
        function RemoveChanButtonPushed(app, event)
            removeindcs = find(strcmp(app.chanList, app.ChanListListBox.Value));
            app.chanList(removeindcs) = [];
            updateChanListBox(app);
        end

        % Button pushed function: AddEStimTrialsButton
        function AddEStimTrialsButtonPushed(app, event)
            addEStimtrials(app);
            disp(app.trialList);
            updateListBox(app);
        end

        % Button pushed function: ShuffleButton
        function ShuffleButtonPushed(app, event)
            shuffleTrials(app);
            updateListBox(app);
        end

        % Button pushed function: ClearTrialsButton
        function ClearTrialsButtonPushed(app, event)
            app.trialList = [];
            updateListBox(app);
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            ShuffleButtonPushed(app, event);
            toXMLButtonPushed(app, event);
            XMLtoDBButtonPushed(app, event);
            msgbox("Generation Done!");
            if app.ClearTrialListAfterGeneratingCheckBox.Value
                ClearTrialsButtonPushed(app, event);
            end
        end

        % Button pushed function: ClearChans
        function ClearChansButtonPushed(app, event)
            app.chanList = [];
            updateChanListBox(app);
        end

        % Value changed function: EnableAmpSettleCheckBox
        function EnableAmpSettleCheckBoxValueChanged(app, event)
            value = app.EnableAmpSettleCheckBox.Value;
            if app.EnableAmpSettleCheckBox.Value==false
                app.PreStimAmpSettleEditField.Enable = 'Off';
                app.PreStimAmpSettleEditField.Editable = 'Off';
                app.PostStimAmpSettleEditField.Enable= 'Off';
                app.PostStimAmpSettleEditField.Editable = 'Off';
                app.MaintainAmpSettleDuringPulseTrainCheckBox.Enable = 'Off';
            else 
                app.PreStimAmpSettleEditField.Enable= 'On';
                app.PreStimAmpSettleEditField.Editable='On';
                app.PostStimAmpSettleEditField.Enable='On';
                app.PostStimAmpSettleEditField.Editable = 'On';        
                if strcmp(app.PulseRepetitionDropDown.Value,'PulseTrain')
                    app.MaintainAmpSettleDuringPulseTrainCheckBox.Enable = 'On';
                else
                    app.MaintainAmpSettleDuringPulseTrainCheckBox.Enable = 'Off';                 
                end
            end
        end

        % Value changed function: EnableChargeRecoveryCheckBox
        function EnableChargeRecoveryCheckBoxValueChanged(app, event)
            value = app.EnableChargeRecoveryCheckBox.Value;
            if app.EnableChargeRecoveryCheckBox.Value==false
                app.PostStimChargeRecoveryOnEditField.Enable='Off';
                app.PostStimChargeRecoveryOnEditField.Editable='Off';
                app.PostStimChargeRecoveryOffEditField.Enable='Off';
                app.PostStimChargeRecoveryOffEditField.Editable='Off';
            else
                app.PostStimChargeRecoveryOnEditField.Enable='On';
                app.PostStimChargeRecoveryOnEditField.Editable='On';
                app.PostStimChargeRecoveryOffEditField.Enable='On';
                app.PostStimChargeRecoveryOffEditField.Editable='On';
            end
        end

        % Value changed function: PulseRepetitionDropDown
        function PulseRepetitionDropDownValueChanged(app, event)
            value = app.PulseRepetitionDropDown.Value;
            if strcmp(app.PulseRepetitionDropDown.Value,'SinglePulse')
                app.NumberPulsesEditField.Enable = 'Off';
                app.NumberPulsesEditField.Editable = 'Off';
                app.PulseTrainPeriodEditField.Enable = 'Off';
                app.PulseTrainPeriodEditField.Editable = 'Off';
                app.PostStimRefractoryEditField.Enable = 'Off';
                app.PostStimRefractoryEditField.Editable = 'Off';
            else
                app.NumberPulsesEditField.Enable = 'On';
                app.NumberPulsesEditField.Editable = 'On';  
                app.PulseTrainPeriodEditField.Enable = 'On';
                app.PulseTrainPeriodEditField.Editable = 'On'; 
                app.PostStimRefractoryEditField.Enable = 'On';
                app.PostStimRefractoryEditField.Editable = 'On';                
            end
            
            
            EnableAmpSettleCheckBoxValueChanged(app);
        end

        % Value changed function: radiusEditFieldMin
        function radiusEditFieldMinValueChanged(app, event)
            value = app.radiusEditFieldMin.Value;
            
        end

        % Value changed function: StimShapeDropDown
        function StimShapeDropDownValueChanged(app, event)
            value = app.StimShapeDropDown.Value;
            if strcmp(app.StimShapeDropDown.Value,'BiphasicWithInterphaseDelay')
                app.dpEditField.Enable ='On';
                app.dpEditField.Editable ='On';
            else
                app.dpEditField.Enable ='Off';
                app.dpEditField.Editable ='Off';                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.VisualTrialsPanel = uipanel(app.UIFigure);
            app.VisualTrialsPanel.Title = 'Visual Trials';
            app.brightnessEditFieldMax = uieditfield(app.VisualTrialsPanel, 'numeric');
            app.durationvisualEditFieldMax = uieditfield(app.VisualTrialsPanel, 'numeric');
            app.sizeEditFieldMax = uieditfield(app.VisualTrialsPanel, 'numeric');
            app.sizeLabel = uilabel(app.VisualTrialsPanel);
            app.sizeLabel.Tooltip = {'In Degrees of Visual Angle'};
            app.sizeLabel.Text = 'size';
            app.sizeEditFieldMin = uieditfield(app.VisualTrialsPanel, 'numeric');
            app.brightnessLabel = uilabel(app.VisualTrialsPanel);
            app.brightnessLabel.Tooltip = {'In Degrees of Visual Angle'};
            app.brightnessLabel.Text = 'brightness';
            app.brightnessEditFieldMin = uieditfield(app.VisualTrialsPanel, 'numeric');
            app.durationLabel = uilabel(app.VisualTrialsPanel);
            app.durationLabel.Tooltip = {'In Degrees of Visual Angle'};
            app.durationLabel.Text = 'duration';
            app.durationvisualEditFieldMin = uieditfield(app.VisualTrialsPanel, 'numeric');
            app.dataEditFieldLabel = uilabel(app.VisualTrialsPanel);
            app.dataEditFieldLabel.Text = 'data';
            app.datavisualEditField = uieditfield(app.VisualTrialsPanel, 'text');
            app.datavisualEditField.Value = 'Visual';
            app.radiusEditFieldMax = uieditfield(app.VisualTrialsPanel, 'numeric');
            app.radiusLabel = uilabel(app.VisualTrialsPanel);
            app.radiusLabel.Tooltip = {'In Degrees of Visual Angle'};
            app.radiusLabel.Text = 'radius';
            app.radiusEditFieldMin = uieditfield(app.VisualTrialsPanel, 'numeric');
            app.radiusEditFieldMin.ValueChangedFcn = createCallbackFcn(app, @radiusEditFieldMinValueChanged, true);
            app.VisualTrialParametersLabel = uilabel(app.VisualTrialsPanel);
            app.VisualTrialParametersLabel.Text = 'Visual Trial Parameters';
            app.numTrialsEditFieldLabel = uilabel(app.VisualTrialsPanel);
            app.numTrialsEditFieldLabel.Text = 'numTrials';
            app.numVisualTrialsEditField = uieditfield(app.VisualTrialsPanel, 'numeric');
            app.AddVisualTrialsButton = uibutton(app.VisualTrialsPanel, 'push');
            app.AddVisualTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @AddVisualTrialsButtonPushed, true);
            app.AddVisualTrialsButton.Text = 'Add Trials';
            app.eyewindowradiusLabel = uilabel(app.VisualTrialsPanel);
            app.eyewindowradiusLabel.Text = 'eye window radius';
            app.eyewindowradiusVisualEditField = uieditfield(app.VisualTrialsPanel, 'numeric');
            app.EStimTrialsPanel = uipanel(app.UIFigure);
            app.EStimTrialsPanel.Title = 'EStim Trials';
            app.ChannelSelectionPanel = uipanel(app.EStimTrialsPanel);
            app.ChannelSelectionPanel.Title = 'Channel Selection';
            app.PortDropDownLabel = uilabel(app.ChannelSelectionPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.ChannelSelectionPanel);
            app.PortDropDown.Items = {'A', 'B', 'C', 'D'};
            app.PortDropDown.Value = 'A';
            app.ChannelSpinnerLabel = uilabel(app.ChannelSelectionPanel);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.ChannelSpinner = uispinner(app.ChannelSelectionPanel);
            app.ChannelSpinner.Limits = [0 31];
            app.AddChanButton = uibutton(app.ChannelSelectionPanel, 'push');
            app.AddChanButton.ButtonPushedFcn = createCallbackFcn(app, @AddChanButtonPushed, true);
            app.AddChanButton.Text = 'Add Chan';
            app.ChanListListBoxLabel = uilabel(app.ChannelSelectionPanel);
            app.ChanListListBoxLabel.Text = 'Chan List';
            app.ChanListListBox = uilistbox(app.ChannelSelectionPanel);
            app.ChanListListBox.Items = {};
            app.ChanListListBox.Value = {};
            app.RemoveChanButton = uibutton(app.ChannelSelectionPanel, 'push');
            app.RemoveChanButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveChanButtonPushed, true);
            app.RemoveChanButton.Tooltip = {'Removes highlighted channel from Chan List'};
            app.RemoveChanButton.Text = 'Remove Chan';
            app.ClearChans = uibutton(app.ChannelSelectionPanel, 'push');
            app.ClearChans.ButtonPushedFcn = createCallbackFcn(app, @ClearChansButtonPushed, true);
            app.ClearChans.Text = 'Clear Chans';
            app.PostTriggerDelayEditFieldLabel = uilabel(app.EStimTrialsPanel);
            app.PostTriggerDelayEditFieldLabel.Text = 'Post Trigger Delay';
            app.PostTriggerDelayEditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.PostTriggerDelayEditField.Limits = [0 500];
            app.PostTriggerDelayEditField.ValueDisplayFormat = '%11.4g ms';
            app.PostTriggerDelayEditField.Tooltip = {'in ms'};
            app.TriggerSourceDropDownLabel = uilabel(app.EStimTrialsPanel);
            app.TriggerSourceDropDownLabel.Text = {'Trigger Source'; ''};
            app.TriggerSourceDropDown = uidropdown(app.EStimTrialsPanel);
            app.TriggerSourceDropDown.Items = {'DigitalIn1', 'DigitalIn2', 'DigitalIn3', 'DigitalIn4', 'DigitialIn5', ''};
            app.TriggerSourceDropDown.Value = 'DigitalIn1';
            app.PulseRepetitionDropDownLabel = uilabel(app.EStimTrialsPanel);
            app.PulseRepetitionDropDownLabel.Text = 'Pulse Repetition';
            app.PulseRepetitionDropDown = uidropdown(app.EStimTrialsPanel);
            app.PulseRepetitionDropDown.Items = {'SinglePulse', 'PulseTrain', ''};
            app.PulseRepetitionDropDown.ValueChangedFcn = createCallbackFcn(app, @PulseRepetitionDropDownValueChanged, true);
            app.PulseRepetitionDropDown.Value = 'SinglePulse';
            app.NumberPulsesEditFieldLabel = uilabel(app.EStimTrialsPanel);
            app.NumberPulsesEditFieldLabel.Text = {'Number Pulses'; ''};
            app.NumberPulsesEditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.NumberPulsesEditField.Limits = [0 256];
            app.NumberPulsesEditField.RoundFractionalValues = 'on';
            app.PulseTrainPeriodEditFieldLabel = uilabel(app.EStimTrialsPanel);
            app.PulseTrainPeriodEditFieldLabel.Text = 'Pulse Train Period';
            app.PulseTrainPeriodEditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.PulseTrainPeriodEditField.Limits = [0 1000];
            app.PulseTrainPeriodEditField.ValueDisplayFormat = '%11.4g ms';
            app.PulseTrainPeriodEditField.Tooltip = {'ms'};
            app.PostStimRefractoryEditFieldLabel = uilabel(app.EStimTrialsPanel);
            app.PostStimRefractoryEditFieldLabel.Text = {'Post Stim Refractory'; ''};
            app.PostStimRefractoryEditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.PostStimRefractoryEditField.Limits = [1 1000];
            app.PostStimRefractoryEditField.ValueDisplayFormat = '%11.4g ms';
            app.PostStimRefractoryEditField.Tooltip = {'ms'; ''};
            app.PostStimRefractoryEditField.Value = 1;
            app.StimShapeDropDownLabel = uilabel(app.EStimTrialsPanel);
            app.StimShapeDropDownLabel.Text = 'Stim Shape';
            app.StimShapeDropDown = uidropdown(app.EStimTrialsPanel);
            app.StimShapeDropDown.Items = {'Biphasic', 'BiphasicWithInterphaseDelay', 'Triphasic', 'Monophasic'};
            app.StimShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @StimShapeDropDownValueChanged, true);
            app.StimShapeDropDown.Value = 'Biphasic';
            app.StimPolarityDropDownLabel = uilabel(app.EStimTrialsPanel);
            app.StimPolarityDropDownLabel.Text = 'Stim Polarity';
            app.StimPolarityDropDown = uidropdown(app.EStimTrialsPanel);
            app.StimPolarityDropDown.Items = {'CathodicCurrentFirst', 'AnodicCurrentFirst', ''};
            app.StimPolarityDropDown.Value = 'CathodicCurrentFirst';
            app.d1EditFieldLabel = uilabel(app.EStimTrialsPanel);
            app.d1EditFieldLabel.Text = 'd1';
            app.d1EditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.d1EditField.ValueDisplayFormat = '%11.4g ms';
            app.d1EditField.Value = 1;
            app.d2EditFieldLabel = uilabel(app.EStimTrialsPanel);
            app.d2EditFieldLabel.Text = 'd2';
            app.d2EditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.d2EditField.ValueDisplayFormat = '%11.4g ms';
            app.d2EditField.Value = 1;
            app.dpEditFieldLabel = uilabel(app.EStimTrialsPanel);
            app.dpEditFieldLabel.Text = 'dp';
            app.dpEditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.dpEditField.ValueDisplayFormat = '%11.4g ms';
            app.a1EditFieldLabel = uilabel(app.EStimTrialsPanel);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.a1EditField.Limits = [0 127.5];
            app.a1EditField.ValueDisplayFormat = '%11.4g μA';
            app.a2EditFieldLabel = uilabel(app.EStimTrialsPanel);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.a2EditField.Limits = [0 127.5];
            app.a2EditField.ValueDisplayFormat = '%11.4g μA';
            app.EnableAmpSettleCheckBox = uicheckbox(app.EStimTrialsPanel);
            app.EnableAmpSettleCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableAmpSettleCheckBoxValueChanged, true);
            app.EnableAmpSettleCheckBox.Text = 'Enable Amp Settle';
            app.PreStimAmpSettleEditFieldLabel = uilabel(app.EStimTrialsPanel);
            app.PreStimAmpSettleEditFieldLabel.Text = 'Pre Stim Amp Settle';
            app.PreStimAmpSettleEditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.PreStimAmpSettleEditField.Limits = [0 1];
            app.PreStimAmpSettleEditField.ValueDisplayFormat = '%11.4g ms';
            app.PostStimAmpSettleEditFieldLabel = uilabel(app.EStimTrialsPanel);
            app.PostStimAmpSettleEditFieldLabel.Text = 'Post Stim Amp Settle';
            app.PostStimAmpSettleEditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.PostStimAmpSettleEditField.Limits = [0 1];
            app.PostStimAmpSettleEditField.ValueDisplayFormat = '%11.4g ms';
            app.MaintainAmpSettleDuringPulseTrainCheckBox = uicheckbox(app.EStimTrialsPanel);
            app.MaintainAmpSettleDuringPulseTrainCheckBox.Text = 'Maintain Amp Settle During Pulse Train';
            app.EnableChargeRecoveryCheckBox = uicheckbox(app.EStimTrialsPanel);
            app.EnableChargeRecoveryCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableChargeRecoveryCheckBoxValueChanged, true);
            app.EnableChargeRecoveryCheckBox.Text = 'Enable Charge Recovery';
            app.PostStimChargeRecoveryOnEditFieldLabel = uilabel(app.EStimTrialsPanel);
            app.PostStimChargeRecoveryOnEditFieldLabel.Text = 'Post Stim Charge Recovery On';
            app.PostStimChargeRecoveryOnEditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.PostStimChargeRecoveryOnEditField.Limits = [0 1];
            app.PostStimChargeRecoveryOnEditField.ValueDisplayFormat = '%11.4g ms';
            app.PostStimChargeRecoveryOffEditFieldLabel = uilabel(app.EStimTrialsPanel);
            app.PostStimChargeRecoveryOffEditFieldLabel.Text = 'Post Stim Charge Recovery Off';
            app.PostStimChargeRecoveryOffEditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.PostStimChargeRecoveryOffEditField.Limits = [0 1];
            app.PostStimChargeRecoveryOffEditField.ValueDisplayFormat = '%11.4g ms';
            app.numTrialsEditFieldLabel_2 = uilabel(app.EStimTrialsPanel);
            app.numTrialsEditFieldLabel_2.Text = 'numTrials';
            app.numEStimTrialsEditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.numEStimTrialsEditField.Value = 10;
            app.AddEStimTrialsButton = uibutton(app.EStimTrialsPanel, 'push');
            app.AddEStimTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @AddEStimTrialsButtonPushed, true);
            app.AddEStimTrialsButton.Text = 'Add Trials';
            app.EyeWindowCoordsYEStimEditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.EyeWindowCoordsYEStimEditField.ValueDisplayFormat = '%11.4g °';
            app.EyeWindowCoordsXYLabel = uilabel(app.EStimTrialsPanel);
            app.EyeWindowCoordsXYLabel.Text = 'Eye Window Coords (XY)';
            app.EyeWindowCoordsXEStimEditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.EyeWindowCoordsXEStimEditField.ValueDisplayFormat = '%11.4g°';
            app.EyeWindowRadiusEditFieldLabel = uilabel(app.EStimTrialsPanel);
            app.EyeWindowRadiusEditFieldLabel.Text = 'Eye Window Radius';
            app.EyeWindowRadiusEStimEditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.DurationLabel = uilabel(app.EStimTrialsPanel);
            app.DurationLabel.Tooltip = {'In Degrees of Visual Angle'};
            app.DurationLabel.Text = 'Duration';
            app.DurationEStimEditField = uieditfield(app.EStimTrialsPanel, 'numeric');
            app.DurationEStimEditField.ValueDisplayFormat = '%11.4g ms';
            app.DataLabel = uilabel(app.EStimTrialsPanel);
            app.DataLabel.Text = 'Data';
            app.DataEStimEditField = uieditfield(app.EStimTrialsPanel, 'text');
            app.DataEStimEditField.Value = 'EStim';
            app.GenerationPanel = uipanel(app.UIFigure);
            app.GenerationPanel.Title = 'Generation';
            app.toXMLButton = uibutton(app.GenerationPanel, 'push');
            app.toXMLButton.ButtonPushedFcn = createCallbackFcn(app, @toXMLButtonPushed, true);
            app.toXMLButton.Text = 'toXML';
            app.XMLtoDBButton = uibutton(app.GenerationPanel, 'push');
            app.XMLtoDBButton.ButtonPushedFcn = createCallbackFcn(app, @XMLtoDBButtonPushed, true);
            app.XMLtoDBButton.Text = 'XML to DB';
            app.TrialListListBoxLabel = uilabel(app.GenerationPanel);
            app.TrialListListBoxLabel.Text = 'Trial List';
            app.TrialListListBox = uilistbox(app.GenerationPanel);
            app.TrialListListBox.Items = {};
            app.TrialListListBox.Value = {};
            app.filepathEditFieldLabel = uilabel(app.GenerationPanel);
            app.filepathEditFieldLabel.Text = 'filepath';
            app.filepathEditField = uieditfield(app.GenerationPanel, 'text');
            app.filepathEditField.Value = '/Users/allenchen/Documents/GitHub/V1Microstim/MATLAB/EStimSpecGen/test.xml';
            app.ShuffleButton = uibutton(app.GenerationPanel, 'push');
            app.ShuffleButton.ButtonPushedFcn = createCallbackFcn(app, @ShuffleButtonPushed, true);
            app.ShuffleButton.Text = 'Shuffle';
            app.ClearTrialsButton = uibutton(app.GenerationPanel, 'push');
            app.ClearTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTrialsButtonPushed, true);
            app.ClearTrialsButton.Text = 'Clear Trials';
            app.GenerateButton = uibutton(app.GenerationPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ClearTrialListAfterGeneratingCheckBox = uicheckbox(app.GenerationPanel);
            app.ClearTrialListAfterGeneratingCheckBox.Text = 'Clear Trial List After Generating';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleUnimodal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end